classdef sugfis_mbgd_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DimensionalityReductionDropDownLabel  matlab.ui.control.Label
        DimensionalityReductionDropDown  matlab.ui.control.DropDown
        SubspcaeDimensionalityEditFieldLabel  matlab.ui.control.Label
        SubspcaeDimensionalityEditField  matlab.ui.control.NumericEditField
        ParameterInitializationDropDownLabel  matlab.ui.control.Label
        ParameterInitializationDropDown  matlab.ui.control.DropDown
        MembershipFunctionMFDropDownLabel  matlab.ui.control.Label
        MembershipFunctionMFDropDown    matlab.ui.control.DropDown
        NumberofMFspervariableEditFieldLabel  matlab.ui.control.Label
        NumberofMFspervariableEditField  matlab.ui.control.NumericEditField
        ParameterOptimizationDropDownLabel  matlab.ui.control.Label
        ParameterOptimizationDropDown   matlab.ui.control.DropDown
        PowerballpowerexponentSliderLabel  matlab.ui.control.Label
        PowerballpowerexponentSlider    matlab.ui.control.Slider
        DropRulePreservationRateLabel   matlab.ui.control.Label
        DropRulepreservationrateSlider  matlab.ui.control.Slider
        log10InitialLearningRateLabel   matlab.ui.control.Label
        log10InitiallearningrateSpinner  matlab.ui.control.Spinner
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        BatchsizeEditFieldLabel         matlab.ui.control.Label
        BatchsizeEditField              matlab.ui.control.NumericEditField
        L2regularizationcoefficientSpinnerLabel  matlab.ui.control.Label
        L2regularizationcoefficientSpinner  matlab.ui.control.Spinner
        LoadDataandRunButton            matlab.ui.control.Button
        UncertainDropDownLabel          matlab.ui.control.Label
        UncertainDropDown               matlab.ui.control.DropDown
        TypeReductionDropDownLabel      matlab.ui.control.Label
        TypeReductionDropDown           matlab.ui.control.DropDown
        RulePruningPDropDown            matlab.ui.control.DropDown
        RulePruningDropDownLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        RMSE                            matlab.ui.control.UIAxes
        ValFL                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataandRunButton
        function LoadDataandRunButtonPushed(app, event)
        
        % Display uigetfile dialog
        filterspec = {'*.mat','MAT Files'};
        [f, p] = uigetfile(filterspec);
        
        % Make sure user didn't cancel uigetfile dialog
        if (ischar(p))
            fname = [p f];
            load(fname,'-mat','XTrain','yTrain','XTune','XTest','yTune','yTest');
            %% normalize y
            yTune=(yTune-mean(yTrain))/std(yTrain);
            yTest=(yTest-mean(yTrain))/std(yTrain);
            yTrain=(yTrain-mean(yTrain))/std(yTrain);
            MF = app.MembershipFunctionMFDropDown.Value;
            DR = app.DimensionalityReductionDropDown.Value;
            nF = app.SubspcaeDimensionalityEditField.Value;
            Init = app.ParameterInitializationDropDown.Value;
            nMFs = app.NumberofMFspervariableEditField.Value;
            Uncertain = app.UncertainDropDown.Value;
            TR = app.TypeReductionDropDown.Value;
            RP = app.RulePruningPDropDown.Value;
            
            Opt = app.ParameterOptimizationDropDown.Value;
            Powerball = app.PowerballpowerexponentSlider.Value;
            DropRule = app.DropRulepreservationrateSlider.Value;
            lr = 10^(app.log10InitiallearningrateSpinner.Value);
            l2 = app.L2regularizationcoefficientSpinner.Value;
            nIt = app.NumberofIterationsEditField.Value;
            Nbs = app.BatchsizeEditField.Value;
            
            switch RP
                case {'None'}
                    RP=nan;
                case {'Vanilla'}
                    RP=nIt/2;
                    nMFs=nMFs*2;
                case {'Adaptive'}
                    RP=[0,nIt/2];
                    nMFs=nMFs*2;
            end
            
            rng(0)
            [tmp,tmpt,AntecedentsB, WB, WAB, WCB, fB]=sugfis_mbgd(XTrain,yTrain,{XTune,XTest},{yTune,yTest},'MF',MF,'DR',DR,'nF',nF,'Init',Init,'nMF',nMFs,'Uncertain',Uncertain,'TR',TR,'RP',RP,'Opt',Opt,'Powerball',Powerball,'DropRule',DropRule,'lr',lr,'l2',l2,'nIt',nIt,'Nbs',Nbs);
            
            [RMSEtrain,RMSEtune,RMSEtest]=deal(tmp,tmpt{1},tmpt{2});
            save('results.mat','MF','DR','nF','Init','nMFs','Uncertain','TR','RP','Opt','Powerball','DropRule','lr','l2','nIt','Nbs','RMSEtrain','RMSEtune','RMSEtest','AntecedentsB','WB','WAB','WCB','fB');
            fis=num2fis(AntecedentsB, WB, WAB);
            
            nPoints=nIt/50+1;
            idp=round(linspace(1,nIt,nPoints));
            set(app.RMSE,'DefaulttextFontName','times new roman','DefaultaxesFontName','times new roman');
            plot(app.RMSE,idp,RMSEtrain(idp),'k',idp,RMSEtune(idp),'b',idp,RMSEtest(idp),'r','linewidth',2)
            hold(app.RMSE,"on")
            i0=0;
            if ~isnan(RP)
                i0=RP(end);
            end
            [minRMSEtune,id]=min(RMSEtune(i0+1:end));
            id=i0+id;
            plot(app.RMSE, [id id],[0 minRMSEtune],'b--','linewidth',2);
            legend(app.RMSE,{'Train','Val.','Test','Best Val. Iter.'},'location','best','box','off');
            set(app.RMSE,'xlim',[1,nIt])
            title(app.RMSE,[f(1:end-4) ' (testRMSE= ' num2str(RMSEtest(id),4) ')']);
            hold(app.RMSE,"off")
            
            set(app.ValFL,'DefaulttextFontName','times new roman','DefaultaxesFontName','times new roman');
            fB=fB/sum(fB);
            plot(app.ValFL,1:length(fB),sort(fB,'descend'),'b','linewidth',2)
            set(app.ValFL,'xlim',[1,length(fB)])
            title(app.ValFL,[f(1:end-4) ' (maxFL/minFL= ' num2str(max(fB)/min(fB),4) ')']);
            
        end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {625, 625};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {301, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DimensionalityReductionDropDownLabel = uilabel(app.GridLayout2);
            app.DimensionalityReductionDropDownLabel.Text = 'Dimensionality Reduction';
            app.DimensionalityReductionDropDown = uidropdown(app.GridLayout2);
            app.DimensionalityReductionDropDown.Items = {'None', 'PCA', 'TLP1', 'TLP2', 'TLPx', 'DR', 'CDR'};
            app.DimensionalityReductionDropDown.Value = 'CDR';
            app.SubspcaeDimensionalityEditFieldLabel = uilabel(app.GridLayout2);
            app.SubspcaeDimensionalityEditFieldLabel.Text = 'Subspcae Dimensionality';
            app.SubspcaeDimensionalityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SubspcaeDimensionalityEditField.Value = 4;
            app.ParameterInitializationDropDownLabel = uilabel(app.GridLayout2);
            app.ParameterInitializationDropDownLabel.Text = 'Parameter Initialization';
            app.ParameterInitializationDropDown = uidropdown(app.GridLayout2);
            app.ParameterInitializationDropDown.Items = {'GP', 'FCMx', 'LogFCM', 'HFCM', 'LogHFCM', 'FCMy', 'kMx', 'kMy'};
            app.ParameterInitializationDropDown.Value = 'FCMx';
            app.MembershipFunctionMFDropDownLabel = uilabel(app.GridLayout2);
            app.MembershipFunctionMFDropDownLabel.Text = 'Membership Function (MF)';
            app.MembershipFunctionMFDropDown = uidropdown(app.GridLayout2);
            app.MembershipFunctionMFDropDown.Items = {'Bell-Shaped', 'Gaussian', 'Trapezoidal', 'Triangular'};
            app.MembershipFunctionMFDropDown.Value = 'Gaussian';
            app.NumberofMFspervariableEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofMFspervariableEditFieldLabel.Text = 'Number of MFs per Variable';
            app.NumberofMFspervariableEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofMFspervariableEditField.Value = 16;
            app.ParameterOptimizationDropDownLabel = uilabel(app.GridLayout2);
            app.ParameterOptimizationDropDownLabel.Text = 'Parameter Optimization';
            app.ParameterOptimizationDropDown = uidropdown(app.GridLayout2);
            app.ParameterOptimizationDropDown.Items = {'AdaBound', 'SGDM', 'Adam', 'AdaBelief'};
            app.ParameterOptimizationDropDown.Value = 'AdaBelief';
            app.PowerballpowerexponentSliderLabel = uilabel(app.GridLayout2);
            app.PowerballpowerexponentSliderLabel.Text = 'Powerball Power Exponent';
            app.PowerballpowerexponentSlider = uislider(app.GridLayout2);
            app.PowerballpowerexponentSlider.Limits = [0 1];
            app.PowerballpowerexponentSlider.MajorTicks = [0 0.5 1];
            app.PowerballpowerexponentSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PowerballpowerexponentSlider.Value = 0.5;
            app.DropRulePreservationRateLabel = uilabel(app.GridLayout2);
            app.DropRulePreservationRateLabel.Text = 'DropRule Preservation Rate';
            app.DropRulepreservationrateSlider = uislider(app.GridLayout2);
            app.DropRulepreservationrateSlider.Limits = [0 1];
            app.DropRulepreservationrateSlider.MajorTicks = [0 0.5 1];
            app.DropRulepreservationrateSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DropRulepreservationrateSlider.Value = 0.5;
            app.log10InitialLearningRateLabel = uilabel(app.GridLayout2);
            app.log10InitialLearningRateLabel.Text = 'log10( Initial Learning Rate )';
            app.log10InitiallearningrateSpinner = uispinner(app.GridLayout2);
            app.log10InitiallearningrateSpinner.Step = 0.1;
            app.log10InitiallearningrateSpinner.Value = -2;
            app.NumberofIterationsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofIterationsEditField.Value = 1000;
            app.BatchsizeEditFieldLabel = uilabel(app.GridLayout2);
            app.BatchsizeEditFieldLabel.Text = 'Mini-Batch Size';
            app.BatchsizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BatchsizeEditField.Value = 64;
            app.L2regularizationcoefficientSpinnerLabel = uilabel(app.GridLayout2);
            app.L2regularizationcoefficientSpinnerLabel.Text = 'L2 Regularization Coefficient';
            app.L2regularizationcoefficientSpinner = uispinner(app.GridLayout2);
            app.L2regularizationcoefficientSpinner.Step = 0.05;
            app.L2regularizationcoefficientSpinner.Value = 0.05;
            app.LoadDataandRunButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataandRunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataandRunButtonPushed, true);
            app.LoadDataandRunButton.Text = 'Load Data and Run';
            app.UncertainDropDownLabel = uilabel(app.GridLayout2);
            app.UncertainDropDownLabel.Text = 'Type-2: Uncertain';
            app.UncertainDropDown = uidropdown(app.GridLayout2);
            app.UncertainDropDown.Items = {'None', 'Mean', 'Variance'};
            app.UncertainDropDown.Value = 'None';
            app.TypeReductionDropDownLabel = uilabel(app.GridLayout2);
            app.TypeReductionDropDownLabel.Text = 'Type-2: Type Reduction';
            app.TypeReductionDropDown = uidropdown(app.GridLayout2);
            app.TypeReductionDropDown.Items = {'Karnik-Mendel', 'Nie-Tan'};
            app.TypeReductionDropDown.Value = 'Karnik-Mendel';
            app.RulePruningPDropDown = uidropdown(app.GridLayout2);
            app.RulePruningPDropDown.Items = {'None', 'Vanilla', 'Adaptive'};
            app.RulePruningPDropDown.Value = 'None';
            app.RulePruningDropDownLabel = uilabel(app.GridLayout2);
            app.RulePruningDropDownLabel.Text = 'Rule Pruninig';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.RMSE = uiaxes(app.GridLayout3);
            title(app.RMSE, '')
            xlabel(app.RMSE, 'Iteration')
            ylabel(app.RMSE, 'RMSE')
            app.ValFL = uiaxes(app.GridLayout3);
            title(app.ValFL, '')
            xlabel(app.ValFL, 'Sorted Rule Index')
            ylabel(app.ValFL, 'Normalized Val. Firing Level')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sugfis_mbgd_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end